classdef emg_rc < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EMGDisplayRCUIFigure          matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        TabControls                   matlab.ui.container.Tab
        CheckBoxDisplayMEP            matlab.ui.control.CheckBox
        CheckBoxRecruitCurve          matlab.ui.control.CheckBox
        FilenameprefixEditFieldLabel  matlab.ui.control.Label
        EditFieldFilenameprefix       matlab.ui.control.EditField
        CheckBoxSavedata              matlab.ui.control.CheckBox
        TabParameters                 matlab.ui.container.Tab
    end


    properties (Access = public)
        params          % parameters of the emg data (sampFreq, pre & postTriggerTime)
        
        emg_data_fig    % figure to display an emg epoch
        h_disp_emg_axes % axes to display emg data epoch
        
        emg_data_mmap   % memory map to emg data from emg_activity_mvc_goal.mlapp
        
        h_emg_line      % handle to emg data line
        h_t_min_line    % handle to vertical line at lower time for finding MEP
        h_t_max_line    % handle to vertical line at upper time for finding MEP
        mep_value_text  % uicontrol to display mep amplitude
        
%         rc_data_mmap    % memory map of data for recruitment_curve.mlapp
        rc_fig          % recruit curve figure
        rc_axes         % axes in the recruit curve fig
        rc_fit_ui       % struct with uicontrols for rc fit
        rc_fit_info     % struct with curve fit info
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            get_emg_rc_parameters(app)
            setup_memmap(app, 'emg_data')
            app.EditFieldFilenameprefix.Value = datestr(now,'yyyymmdd_');

        end

        % Value changed function: CheckBoxDisplayMEP
        function disp_mep_checkbox(app, event)
            if app.CheckBoxDisplayMEP.Value
                display_mep(app, 'start')
            else
                display_mep(app, 'stop')
            end
        end

        % Value changed function: CheckBoxRecruitCurve
        function rc_checkbox(app, event)
            if app.CheckBoxRecruitCurve.Value
                display_rc(app, 'start')
            else
                display_rc(app, 'stop')
            end
        end

        % Callback function
        function magspy_checkbox(app, event)
            if app.CheckBoxMagSpy.Value
%                 [status, result] = system( 'matlab -r magspy &');
            else
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EMGDisplayRCUIFigure = uifigure;
            app.EMGDisplayRCUIFigure.Name = 'EMG Display & RC';
            app.TabGroup = uitabgroup(app.EMGDisplayRCUIFigure);
            app.TabControls = uitab(app.TabGroup);
            app.TabControls.Title = 'Controls';
            app.CheckBoxDisplayMEP = uicheckbox(app.TabControls);
            app.CheckBoxDisplayMEP.ValueChangedFcn = createCallbackFcn(app, @disp_mep_checkbox, true);
            app.CheckBoxDisplayMEP.Text = 'Display MEP';
            app.CheckBoxRecruitCurve = uicheckbox(app.TabControls);
            app.CheckBoxRecruitCurve.ValueChangedFcn = createCallbackFcn(app, @rc_checkbox, true);
            app.CheckBoxRecruitCurve.Text = 'Recruitment Curve';
            app.FilenameprefixEditFieldLabel = uilabel(app.TabControls);
            app.FilenameprefixEditFieldLabel.Text = 'File name prefix';
            app.EditFieldFilenameprefix = uieditfield(app.TabControls, 'text');
            app.CheckBoxSavedata = uicheckbox(app.TabControls);
            app.CheckBoxSavedata.Text = 'Save data';
            app.TabParameters = uitab(app.TabGroup);
            app.TabParameters.Title = 'Parameters';
        end
    end

    methods (Access = public)

        % Construct app
        function app = emg_rc

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EMGDisplayRCUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EMGDisplayRCUIFigure)
        end
    end
end