classdef emg_trace_viewer < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		EMG_Trace_Viewer_Fig       matlab.ui.Figure
		GridLayout                 matlab.ui.container.GridLayout
		LeftPanel                  matlab.ui.container.Panel
		TMSAnalysisHierarchyLabel  matlab.ui.control.Label
		SubjectTree                matlab.ui.container.Tree
		RightPanel                 matlab.ui.container.Panel
	end

	% Properties that correspond to apps with auto-reflow
	properties (Access = private)
		onePanelWidth = 576;
	end

	% Callbacks that handle component events
	methods (Access = private)

		% Code that executes after component creation
		function startupFcn(app)
			% look for analysis info files
			analysis_dir = pwd;
			if ~contains(analysis_dir, [filesep 'tms'], "IgnoreCase", true) || ...
					~contains(analysis_dir, [filesep 'analysis' filesep], "IgnoreCase", true)
				uialert(app.TMSInfotoDatabaseUIFigure, {'Current folder is not within an Analysis hierchy.' ...
					'In the dialog box, choose an Analysis/TMS folder.'}, 'Analysis Folder', 'Icon', 'warning')
				analysis_dir = uigetdir(pwd, 'Select TMS analysis folder');
				if analysis_dir==0
					uialert(app.TMSInfotoDatabaseUIFigure, 'No folder was selected.', 'No selection', 'Icon', 'warning')
					return
				end
			end
			% list of all emg trace files


		end

		% Changes arrangement of the app based on UIFigure width
		function updateAppLayout(app, event)
			currentFigureWidth = app.EMG_Trace_Viewer_Fig.Position(3);
			if(currentFigureWidth <= app.onePanelWidth)
			    % Change to a 2x1 grid
			    app.GridLayout.RowHeight = {480, 480};
			    app.GridLayout.ColumnWidth = {'1x'};
			    app.RightPanel.Layout.Row = 2;
			    app.RightPanel.Layout.Column = 1;
			else
			    % Change to a 1x2 grid
			    app.GridLayout.RowHeight = {'1x'};
			    app.GridLayout.ColumnWidth = {220, '1x'};
			    app.RightPanel.Layout.Row = 1;
			    app.RightPanel.Layout.Column = 2;
			end
		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			app.EMG_Trace_Viewer_Fig = uifigure('Visible', 'off');
			app.EMG_Trace_Viewer_Fig.Name = 'MATLAB App';
			app.EMG_Trace_Viewer_Fig.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
			app.GridLayout = uigridlayout(app.EMG_Trace_Viewer_Fig);
			app.LeftPanel = uipanel(app.GridLayout);
			app.SubjectTree = uitree(app.LeftPanel);
			app.TMSAnalysisHierarchyLabel = uilabel(app.LeftPanel);
			app.TMSAnalysisHierarchyLabel.Text = 'TMS Analysis Hierarchy';
			app.RightPanel = uipanel(app.GridLayout);
			% Show the figure after all components are created
			app.EMG_Trace_Viewer_Fig.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = emg_trace_viewer

			% Create UIFigure and components
			createComponents(app)

			% Register the app with App Designer
			registerApp(app, app.EMG_Trace_Viewer_Fig)

			% Execute the startup function
			runStartupFcn(app, @startupFcn)

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.EMG_Trace_Viewer_Fig)
		end
	end
end