classdef review_emg_rc < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ReviewEMGRCUIFigure          matlab.ui.Figure
        EMGDataTxtEditFieldLabel     matlab.ui.control.Label
        EMGDataTxtEditField          matlab.ui.control.EditField
        EmgDataSelectButton          matlab.ui.control.Button
        MMaxSelectButton             matlab.ui.control.Button
        MMaxExcelEditFieldLabel      matlab.ui.control.Label
        MMaxExcelEditField           matlab.ui.control.EditField
        SessionEditFieldLabel        matlab.ui.control.Label
        SessionEditField             matlab.ui.control.EditField
        MuscleEditFieldLabel         matlab.ui.control.Label
        MuscleEditField              matlab.ui.control.EditField
        MmaxVEditFieldLabel          matlab.ui.control.Label
        MmaxEditField                matlab.ui.control.NumericEditField
        DatapointsCSVEditFieldLabel  matlab.ui.control.Label
        DatapointsCSVEditField       matlab.ui.control.EditField
        MmaxtoRCButton               matlab.ui.control.Button
        ButtonGroup                  matlab.ui.container.ButtonGroup
        ButtonRc                     matlab.ui.control.RadioButton
        ButtonSici                   matlab.ui.control.RadioButton
    end


    properties (Access = public)
        params          % parameters of the emg data (sampFreq, pre & postTriggerTime)
        
        emg_data_fig    % figure to display an emg epoch
        h_disp_emg_axes % axes to display emg data epoch
        h_emg_line      % handle to emg data line
        h_t_min_line    % handle to vertical line at lower time for finding MEP
        h_t_max_line    % handle to vertical line at upper time for finding MEP
        
        h_uitable       % table with rc data point info
        row_displayed   % the table row number being displayed
        emg_data        % from emg_data.txt, col 1 = magstim, cols2-301 are emg data
        emg_data_num_vals_ignore % the number of columns of data to ignore in the emg data file
                        % early versions had col1 = magstim value, then emg data
                        % after 2019-06-01, col1 = use/don't use (0 or 1), col2 = magstim value, then emg data
        
        h_radio_mep     % radiobutton group to choose between peak-to-peak & area under the curve
        h_pre_stim_emg_line % pre stim emg line
        h_emg_auc_patch % emg area under the curve patch
        h_edit_mep_begin % edit field with the value of h_t_min_line
        h_edit_mep_end   % edit field with the value of h_t_max_line
%         preEmgMinEditField  % parameters to recompute pre
        
        rc_fig          % recruitment curve figure
        rc_axes         % rc axes
        rc_fit_ui       % struct with fit info edit & text uicontrols
        rc_fit_info     % computed rc fit info
        rc_highlight_line % datapoint highlighted because of selection in datapoint table
       
        sici_fig        % sici / icf figure
        sici_axes       % axes for sici mep values
        sici_ui         % struct with uicontrols for sici
        sici_info       % struct with sici info
        
    end


    methods (Access = private)

        % Button pushed function: MMaxSelectButton
        function MMaxSelectButtonPushed(app, event)
            [fname, pname] = uigetfile('*.xlsx', 'Pick M-max Excel File');
            if ~isequal(fname,0) && ~isequal(pname,0)
                app.MMaxExcelEditField.Value = fullfile(pname, fname);
                app.MmaxEditField.Value = parse_mmax_excel_for_m_max(app.MMaxExcelEditField.Value, ...
                    app.SessionEditField.Value, app.MuscleEditField.Value);
            end
        end

        % Button pushed function: EmgDataSelectButton
        function EmgDataSelectButtonPushed(app, event)
            [fname, pname] = uigetfile('*.txt', 'Pick EMG Data txt File');
            if ~isequal(fname,0) && ~isequal(pname,0)
                app.EMGDataTxtEditField.Value = fullfile(pname, fname);
                EMGDataTxtEditFieldValueChanged(app)
            end
        end

        % Value changed function: EMGDataTxtEditField
        function EMGDataTxtEditFieldValueChanged(app, event)
            value = app.EMGDataTxtEditField.Value;
            % update gui
            [session, muscle] = parse_fname_for_session_muscle(value);
            app.SessionEditField.Value = session;
            app.MuscleEditField.Value = muscle;
            app.MmaxEditField.Value = parse_mmax_excel_for_m_max(app.MMaxExcelEditField.Value, ...
                    app.SessionEditField.Value, app.MuscleEditField.Value);
            % load emg data
            app.emg_data = load(value);
            app.emg_data_num_vals_ignore = 2; % files created after 2019-06-01 have 2 values before the emg data
            file_info = dir(value); 
            if file_info.datenum < datenum(2019,06,01)
                % files created before 2019-06-01 have 1 value before the emg data
                app.emg_data_num_vals_ignore = 1;
            end
                
                      
            % update radiobox for rc vs sici
            tmp = regexpi(app.EMGDataTxtEditField.Value, '_((rc)|(sici))_', 'match');
            if ~isempty(tmp)
                switch tmp{1}
                    case '_rc_'
                        app.ButtonRc.Value = 1;
                    case '_sici_'
                        app.ButtonSici.Value = 1;
                end
            end
            
            % init fig with emg data & table and fig with RC
            init_review_figs(app)
            
            % change the datapoints default filename 
            app.DatapointsCSVEditField.Value = strrep(value, 'emg_data.txt', 'datapoints.csv');
            DatapointsCSVEditFieldValueChanged(app)
 
        end

        % Value changed function: SessionEditField
        function SessionEditFieldValueChanged(app, event)
            value = app.SessionEditField.Value;
            app.MmaxEditField.Value = parse_mmax_excel_for_m_max(app.MMaxExcelEditField.Value, ...
                    app.SessionEditField.Value, app.MuscleEditField.Value);
        end

        % Value changed function: DatapointsCSVEditField
        function DatapointsCSVEditFieldValueChanged(app, event)
            value = app.DatapointsCSVEditField.Value;
            % read in the rc datapoints table
            if exist(value, 'file')
                tbl = readtable(value);
            else
                % compute the table 
                tbl = table();
            end
            % initialize the datapoint uitable
            init_datapoint_table(app, tbl)
            % put the datapoints in the rc fig
            load_stim_emg_data([],[], app)
        end

        % Button pushed function: MmaxtoRCButton
        function MmaxtoRCButtonPushed(app, event)
            app.rc_fit_ui.edNormFactor.String = num2str(app.MmaxEditField.Value);
            rc_change_norm_factor([], [], app)
        end

        % Close request function: ReviewEMGRCUIFigure
        function ReviewEMGRCUIFigureCloseRequest(app, event)
            % close figures before deleting the app
            if isgraphics(app.rc_fig)
                delete(app.rc_fig)
            end
            if isgraphics(app.sici_fig)
                delete(app.sici_fig)
            end
            if isgraphics(app.emg_data_fig)
                delete(app.emg_data_fig)
            end
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ReviewEMGRCUIFigure = uifigure;
            app.ReviewEMGRCUIFigure.Name = 'Review EMG RC';
            app.ReviewEMGRCUIFigure.CloseRequestFcn = createCallbackFcn(app, @ReviewEMGRCUIFigureCloseRequest, true);
            app.EMGDataTxtEditFieldLabel = uilabel(app.ReviewEMGRCUIFigure);
            app.EMGDataTxtEditFieldLabel.Text = 'EMG Data Txt ';
            app.EMGDataTxtEditField = uieditfield(app.ReviewEMGRCUIFigure, 'text');
            app.EMGDataTxtEditField.ValueChangedFcn = createCallbackFcn(app, @EMGDataTxtEditFieldValueChanged, true);
            app.EmgDataSelectButton = uibutton(app.ReviewEMGRCUIFigure, 'push');
            app.EmgDataSelectButton.ButtonPushedFcn = createCallbackFcn(app, @EmgDataSelectButtonPushed, true);
            app.EmgDataSelectButton.Text = 'Select';
            app.MMaxSelectButton = uibutton(app.ReviewEMGRCUIFigure, 'push');
            app.MMaxSelectButton.ButtonPushedFcn = createCallbackFcn(app, @MMaxSelectButtonPushed, true);
            app.MMaxSelectButton.Text = 'Select';
            app.MMaxExcelEditFieldLabel = uilabel(app.ReviewEMGRCUIFigure);
            app.MMaxExcelEditFieldLabel.Text = 'M-Max Excel';
            app.MMaxExcelEditField = uieditfield(app.ReviewEMGRCUIFigure, 'text');
            app.SessionEditFieldLabel = uilabel(app.ReviewEMGRCUIFigure);
            app.SessionEditFieldLabel.Text = 'Session';
            app.SessionEditField = uieditfield(app.ReviewEMGRCUIFigure, 'text');
            app.SessionEditField.ValueChangedFcn = createCallbackFcn(app, @SessionEditFieldValueChanged, true);
            app.MuscleEditFieldLabel = uilabel(app.ReviewEMGRCUIFigure);
            app.MuscleEditFieldLabel.Text = 'Muscle';
            app.MuscleEditField = uieditfield(app.ReviewEMGRCUIFigure, 'text');
            app.MmaxVEditFieldLabel = uilabel(app.ReviewEMGRCUIFigure);
            app.MmaxVEditFieldLabel.Text = 'M-max (µV)';
            app.MmaxEditField = uieditfield(app.ReviewEMGRCUIFigure, 'numeric');
            app.MmaxEditField.Value = 1;
            app.DatapointsCSVEditFieldLabel = uilabel(app.ReviewEMGRCUIFigure);
            app.DatapointsCSVEditFieldLabel.Text = 'Datapoints CSV';
            app.DatapointsCSVEditField = uieditfield(app.ReviewEMGRCUIFigure, 'text');
            app.DatapointsCSVEditField.ValueChangedFcn = createCallbackFcn(app, @DatapointsCSVEditFieldValueChanged, true);
            app.MmaxtoRCButton = uibutton(app.ReviewEMGRCUIFigure, 'push');
            app.MmaxtoRCButton.ButtonPushedFcn = createCallbackFcn(app, @MmaxtoRCButtonPushed, true);
            app.MmaxtoRCButton.Text = 'M-max to RC';
            app.ButtonGroup = uibuttongroup(app.ReviewEMGRCUIFigure);
            app.ButtonRc = uiradiobutton(app.ButtonGroup);
            app.ButtonRc.Text = 'Recruit Curve';
            app.ButtonRc.Value = true;
            app.ButtonSici = uiradiobutton(app.ButtonGroup);
            app.ButtonSici.Text = 'SICI/ICF';
        end
    end

    methods (Access = public)

        % Construct app
        function app = review_emg_rc

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ReviewEMGRCUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ReviewEMGRCUIFigure)
        end
    end
end